classdef Block_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SendToArduinoButton             matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CopyButton                      matlab.ui.control.Button
        SLabel                          matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        ELabel                          matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        w5u                             matlab.ui.control.EditField
        s1u                             matlab.ui.control.EditField
        s2u                             matlab.ui.control.EditField
        s3u                             matlab.ui.control.EditField
        e5u                             matlab.ui.control.EditField
        n3u                             matlab.ui.control.EditField
        n2u                             matlab.ui.control.EditField
        n1u                             matlab.ui.control.EditField
        w6l                             matlab.ui.control.EditField
        w5l                             matlab.ui.control.EditField
        w4l                             matlab.ui.control.EditField
        s1l                             matlab.ui.control.EditField
        s2l                             matlab.ui.control.EditField
        s3l                             matlab.ui.control.EditField
        e6l                             matlab.ui.control.EditField
        e5l                             matlab.ui.control.EditField
        e4l                             matlab.ui.control.EditField
        n3l                             matlab.ui.control.EditField
        n2l                             matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        n1l                             matlab.ui.control.EditField
        CurrentBlockInfoEditField       matlab.ui.control.EditField
        CurrentBlockInfoEditFieldLabel  matlab.ui.control.Label
        PrintButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Uno % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             COM = 'COM7';                  % Set this to match Arduino Com port
%             BaudRate = 9600;               % Set this to match Arduino baud rate
%             app.Uno = serialport(COM,BaudRate); % Device
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)

            info = "";
            locVec = ["n1l" "n2l" "n3l" "n1u" "n2u" "n3u" "e4l" "e5l" "e6l" "e5u" "w4l" "w5l" "w6l" "w5u" "s1l" "s2l" "s3l" "s1u" "s2u" "s3u"  ];
            colorsVec = [app.n1l.Value app.n2l.Value app.n3l.Value app.n1u.Value app.n2u.Value app.n3u.Value app.e4l.Value app.e5l.Value app.e6l.Value app.e5u.Value app.w4l.Value app.w5l.Value app.w6l.Value app.w5u.Value app.s1l.Value app.s2l.Value app.s3l.Value app.s1u.Value app.s2u.Value app.s3u.Value];
        
            j = 1;
            num_blocks = 0;
            for i = 1:length(colorsVec)
                if strcmp(colorsVec(i),"r") || strcmp(colorsVec(i),"y") || strcmp(colorsVec(i),"b")
                    infoVec(j) = locVec(i) + colorsVec(i);
                    info = info + infoVec(j);
                    j = j + 1;
                    num_blocks = num_blocks + 1;
                end
            end

            app.CurrentBlockInfoEditField.Value = "!" + int2str(num_blocks) + info;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.n1l.Value = "x";
            app.n2l.Value = "x";
            app.n3l.Value = "x";
            app.n1u.Value = "x";
            app.n2u.Value = "x";
            app.n3u.Value = "x";
            app.e4l.Value = "x";
            app.e5l.Value = "x";
            app.e6l.Value = "x";
            app.e5u.Value = "x";
            app.w4l.Value = "x";
            app.w5l.Value = "x";
            app.w6l.Value = "x";
            app.w5u.Value = "x"; 
            app.s1l.Value = "x";
            app.s2l.Value = "x";
            app.s3l.Value = "x";
            app.s1u.Value = "x";
            app.s2u.Value = "x";
            app.s3u.Value = "x";   
            app.CheckBox.Value = 0;
            app.CurrentBlockInfoEditField.Value = "";
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            clipboard("copy",app.CurrentBlockInfoEditField.Value)
            app.CheckBox.Value = 1;
            pause(2);
            app.CheckBox.Value = 0;
        end

        % Button pushed function: SendToArduinoButton
        function SendToArduinoButtonPushed(app, event)
%             To_Send = app.CurrentBlockInfoEditField.Value;
%             writeline(app.Uno,To_Send);
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.EditField.Value = readline(Uno);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrintButton = uibutton(app.UIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.CurrentBlockInfoEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentBlockInfoEditFieldLabel.Text = 'Current Block Info';
            app.CurrentBlockInfoEditField = uieditfield(app.UIFigure, 'text');
            app.n1l = uieditfield(app.UIFigure, 'text');
            app.n1l.Placeholder = 'x';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Chassis.JPG';
            app.n2l = uieditfield(app.UIFigure, 'text');
            app.n2l.Placeholder = 'x';
            app.n3l = uieditfield(app.UIFigure, 'text');
            app.n3l.Placeholder = 'x';
            app.e4l = uieditfield(app.UIFigure, 'text');
            app.e4l.Placeholder = 'x';
            app.e5l = uieditfield(app.UIFigure, 'text');
            app.e5l.Placeholder = 'x';
            app.e6l = uieditfield(app.UIFigure, 'text');
            app.e6l.Placeholder = 'x';
            app.s3l = uieditfield(app.UIFigure, 'text');
            app.s3l.Placeholder = 'x';
            app.s2l = uieditfield(app.UIFigure, 'text');
            app.s2l.Placeholder = 'x';
            app.s1l = uieditfield(app.UIFigure, 'text');
            app.s1l.Placeholder = 'x';
            app.w4l = uieditfield(app.UIFigure, 'text');
            app.w4l.Placeholder = 'x';
            app.w5l = uieditfield(app.UIFigure, 'text');
            app.w5l.Placeholder = 'x';
            app.w6l = uieditfield(app.UIFigure, 'text');
            app.w6l.Placeholder = 'x';
            app.n1u = uieditfield(app.UIFigure, 'text');
            app.n1u.Placeholder = 'x';
            app.n2u = uieditfield(app.UIFigure, 'text');
            app.n2u.Placeholder = 'x';
            app.n3u = uieditfield(app.UIFigure, 'text');
            app.n3u.Placeholder = 'x';
            app.e5u = uieditfield(app.UIFigure, 'text');
            app.e5u.Placeholder = 'x';
            app.s3u = uieditfield(app.UIFigure, 'text');
            app.s3u.Placeholder = 'x';
            app.s2u = uieditfield(app.UIFigure, 'text');
            app.s2u.Placeholder = 'x';
            app.s1u = uieditfield(app.UIFigure, 'text');
            app.s1u.Placeholder = 'x';
            app.w5u = uieditfield(app.UIFigure, 'text');
            app.w5u.Placeholder = 'x';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.SendToArduinoButton = uibutton(app.UIFigure, 'push');
            app.SendToArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @SendToArduinoButtonPushed, true);
            app.SendToArduinoButton.Text = 'Send To Arduino';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Block_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end